classdef Copy_of_MonitorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HRLabel               matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        BPMLabel              matlab.ui.control.Label
        HR_value_label        matlab.ui.control.Label
        SpO2Label             matlab.ui.control.Label
        PercentageLabel       matlab.ui.control.Label
        SpO2_value_label      matlab.ui.control.Label
        StartStopPanel        matlab.ui.container.Panel
        StartButton           matlab.ui.control.Button
        TrendsButton          matlab.ui.control.Button
        AlarmThresholdsPanel  matlab.ui.container.Panel
        SpO2Panel             matlab.ui.container.Panel
        MinEditFieldLabel_2   matlab.ui.control.Label
        MinEditLimitSpO2      matlab.ui.control.NumericEditField
        MaxLabel_5            matlab.ui.control.Label
        MaxEditLimitSpO2      matlab.ui.control.NumericEditField
        HRPanel               matlab.ui.container.Panel
        MaxLabel_4            matlab.ui.control.Label
        MaxEditLimitHR        matlab.ui.control.NumericEditField
        MinEditFieldLabel     matlab.ui.control.Label
        MinEditLimitHR        matlab.ui.control.NumericEditField
        EditButton            matlab.ui.control.Button
        Image_AlarmOn         matlab.ui.control.Image
        Image_AlarmPaused     matlab.ui.control.Image
        MuteAlarm             matlab.ui.control.StateButton
        ECGUIAxes             matlab.ui.control.UIAxes
        SpO2UIAxes            matlab.ui.control.UIAxes
        MinEditLimitHR_2      matlab.ui.control.NumericEditField
        MaxEditLimitHR_2      matlab.ui.control.NumericEditField
        MinEditLimitSpO2_2    matlab.ui.control.NumericEditField
        MaxEditLimitSpO2_2    matlab.ui.control.NumericEditField
        EditthresholdButton   matlab.ui.control.Button
        StartStopPanel_2      matlab.ui.container.Panel
        StartButton_2         matlab.ui.control.Button
        TrendsButton_2        matlab.ui.control.Button
        alarmButton           matlab.ui.control.Button
        Button                matlab.ui.control.Button
    end

    
    properties (Access = private)
        SaveDialog                   % Dialog box app
        EditThresholdDialog          % Dialog for edit threshold
        CurrentHR
        CurrentSpO2
        CurrentTemp
        AlarmState
        DataTimer                    % Timer for reading data from arduino
        S_connection                 % Serial object for dataprocessing
        progressbar                  % Feedback to user
        lastAlarm = tic              % Timestamp for last alarm blink
        MonitorOn = false
        player                       % alarm sound
        soundAlarm                   %
        ECGData = zeros(1,500)
        ECGPosition = 1;             % The position in the ECGData array - acts like a 'counter'
        SpO2Count = 1;
        SpO2data = zeros(1,50);
        lastAlarmSound = tic               % Timestamp for last alarm audio played
    end
    
    properties (Access = public)
        MeasuredValuesTable                   % Table for measure values
        AlarmTable                     % Table for alarm values
        AlarmThresholdsTable
        ii = 1;                        % counter for alarm id in table
        jj = 1;                        % counter for measure id in table
        kk = 1;
    end


    methods (Access = public) % public function can be called from ekstern fuctions
        
        
        function CheckAlarmValues(app)
            % flashing alarm
            if toc(app.lastAlarm) > 1
                labelState = false;
                app.lastAlarm = tic;
            else
                labelState = true;
            end
            
            % check if the monitor is on/off
            if app.MonitorOn
                app.CurrentHR = str2double(app.HR_value_label.Text);
                app.CurrentSpO2 = str2double(app.SpO2_value_label.Text);
            else % if off, the alarm should stop
                app.CurrentHR = app.MinEditLimitHR.Value;
                app.CurrentSpO2 = app.MinEditLimitSpO2.Value;
            end
            
            %Save measured values in table
            UpdateMeasuredValuesTable(app, app.jj, app.CurrentHR, app.CurrentSpO2, datetime('now','Format','HH:mm'));
            app.jj=app.jj+1; % counter for the table
            
            app.soundAlarm = false;
            
            if app.HR_value_label.FontColor == [1,0,0]
                newAlarmHR = false;
            else
                newAlarmHR = true;
            end
            
            if app.SpO2_value_label.FontColor == [1,0,0]
                newAlarmSpO2 = false;
            else
                newAlarmSpO2 = true;
            end
            
            % Checks all 3 mesasure values
            if app.CurrentHR > app.MaxEditLimitHR.Value || app.CurrentHR < app.MinEditLimitHR.Value
                app.HR_value_label.FontColor = 'r';
                app.HR_value_label.Visible = labelState; % flash
                app.soundAlarm = true;
                
                if newAlarmHR
                    %Save alarm in table
                    UpdateAlarmTable(app, app.ii, "HR", app.CurrentHR, app.MinEditLimitHR.Value, app.MaxEditLimitHR.Value, datetime('now','Format','HH:mm'));
                    app.ii=app.ii+1; % counter for the table
                end
            else
                app.HR_value_label.FontColor = "0.39,0.83,0.07";
                app.HR_value_label.Visible = 'on';
            end
            if app.CurrentSpO2 > app.MaxEditLimitSpO2.Value || app.CurrentSpO2 < app.MinEditLimitSpO2.Value
                app.SpO2_value_label.FontColor = 'r';
                app.SpO2_value_label.Visible = labelState;
                app.soundAlarm = true;
                
                if newAlarmSpO2
                    %Save alarm in table
                    UpdateAlarmTable(app, app.ii, "SpO2", app.CurrentSpO2, app.MinEditLimitSpO2.Value, app.MaxEditLimitSpO2.Value, datetime('now','Format',"HH:mm"));
                    app.ii=app.ii+1;
                end
            else
                app.SpO2_value_label.FontColor = "0.07,0.62,1.00";
                app.SpO2_value_label.Visible = 'on';
            end
            
            
            % if just one measure value is critical alarm should start
            if app.soundAlarm
                app.Image_AlarmOn.Visible = ~app.MuteAlarm.Value; % show/hide symbol
                if ~app.MuteAlarm.Value
                    if toc(app.lastAlarmSound) < 10
                        %nothing                        
                    else                        
                        play(app.player);
                        app.lastAlarmSound = tic;
                    end
                    
                    app.MuteAlarm.Enable = true;
                end
                % Show button for mute alarm
                app.MuteAlarm.Visible = true;
            else
                app.Image_AlarmOn.Visible = false;
                app.Image_AlarmPaused.Visible = false;
                app.MuteAlarm.Enable = false;
                app.MuteAlarm.Visible = false;
            end
            
        end
    end
    
    methods (Access = private)
        
        
        function SetUpConnection(app)
            % Setup serial connection
            comPort = 'COM5';
            
            % Open connection
            app.S_connection = serialport(comPort,9600);
            disp('Serial connection is open...')
        end
        
        function StopMonitor(app)
            app.S_connection = [];
            disp('Serial Connection is closed')
        end
        
        function getMeasurands(app,~,~)
            rawData = strtrim(readline(app.S_connection));
            rawData = split(rawData,':'); % splits data -> HR:SpO2:Conficence:Status:ECG[array]
            
            % Get pulse & SpO2
            HR_SpO2_Data = str2double(rawData(1:4)); % get only HR:SpO2:Conficence:Status
            
            % Check confidence is over 95% and status of finger detected (equals 3)
            if HR_SpO2_Data(3)>= 95 && HR_SpO2_Data(4)==3
                app.HR_value_label.Text = num2str(HR_SpO2_Data(1));
                app.SpO2_value_label.Text = num2str(HR_SpO2_Data(2));
                drawnow % this updates the GUI immediatly at every iteration
                app.SpO2data(app.SpO2Count) = HR_SpO2_Data(2);
                
                app.plotSpO2();
                
                CheckAlarmValues(app);
            else
                disp('Finger detection error. Try replace finger')
            end
            
            % Get ECG data
            NewECGData = rawData(5);
            NewECGData = split(NewECGData,',')'; % transvers: vector --> array
            NewECGData = str2double(NewECGData(1:end-1)); % returns array with 10 elements
            app.ECGData(app.ECGPosition:app.ECGPosition + 9) = NewECGData;
            
            app.plotECG();
        end
        
        function plotECG(app)
            %app.ECGUIAxes.YLim = [0 1000];
            plot(app.ECGUIAxes, app.ECGData,'color','g');
            xline(app.ECGUIAxes, app.ECGPosition + 9,'color',[0.8, 0.8, 0.8]);
            app.ECGPosition = app.ECGPosition + 10;
            
            if app.ECGPosition == length(app.ECGData) + 1
                app.ECGPosition = 1;
            end
        end
        
        function plotSpO2(app)
            plot(app.SpO2UIAxes, app.SpO2data,'color','b');
            ylim(app.SpO2UIAxes,[0 100]);
            xline(app.SpO2UIAxes,app.SpO2Count,'color',[0.8, 0.8, 0.8]);
            app.SpO2Count = app.SpO2Count + 1;
            
            if app.SpO2Count > length(app.SpO2data)
                app.SpO2Count = 1;
            end
        end
        
        % used for test
        function gethardcodedMeasurands(app,~,~)
            
          
            pause(0.4);
            r = randi([130,134],1);
            r2 = randi([97,98],1);
            app.HR_value_label.Text = num2str(r(1));
            app.SpO2_value_label.Text = num2str(r2(1));
            drawnow % this updates the GUI immediatly at every iteration
            app.SpO2data(app.SpO2Count) = r2(1);
            app.plotSpO2();
            CheckAlarmValues(app);
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "Diabetes Classifier";
            
            app.ECGUIAxes.XTick = [];
            app.ECGUIAxes.YTick = [];
            app.SpO2UIAxes.XTick = [];
            app.SpO2UIAxes.YTick = [];
            
            delete(timerfindall);
            
            % Creating timer for getting measurement values
            app.DataTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                "Period", 0.5, ... % OBS!!!! Change value to how often the gui should update
                "BusyMode", "drop", ... % ingen kø, henter kun data hvis der er noget
                "Name", "DataTimer", ...
                "TimerFcn", {@app.gethardcodedMeasurands});
            
            % load melody for alarm audio
            [y, Fs] = audioread('alarm10pulses.wav');
            app.player = audioplayer(y, Fs);
            
            %Create table for alarm values
            sz = [500 6];
            alarm_varTypes = {'double','string','double','double','double','datetime'};
            alarm_varNames = {'ID','MeasureType','MeasureValue','ThresholdMin','ThresholdMax','Time'};
            app.AlarmTable = table('Size',sz,'VariableTypes',alarm_varTypes,'VariableNames',alarm_varNames);
            
            %Create table for measure values
            sz = [1000 4];
            measure_varTypes = {'double','double','double','datetime'};
            measure_varNames = {'ID','HR','SpO2','Time'};
            app.MeasuredValuesTable = table('Size',sz,'VariableTypes',measure_varTypes,'VariableNames',measure_varNames);
            
            %Create table for alarmthreshold values
            sz = [20 6];
            thresholds_varTypes = {'double','double','double','double','double','datetime'};
            thresholds_varNames = {'ID','minHR','maxHR','minSpO2','maxSpO2','TimeChanged'};
            app.AlarmThresholdsTable = table('Size',sz,'VariableTypes',thresholds_varTypes,'VariableNames',thresholds_varNames);
            
            UpdateAlarmThresholdsTable(app,app.kk,app.MinEditLimitHR_2.Value, app.MaxEditLimitHR_2.Value, app.MinEditLimitSpO2_2.Value, app.MaxEditLimitSpO2_2.Value, datetime('now','Format','HH:mm'));
            app.kk=app.kk+1;
        end

        % Button pushed function: TrendsButton
        function TrendsButtonPushed(app, event)
            % Åbner SaveDialogApp
            app.SaveDialog = SaveDialogApp(app, app.MeasuredValuesTable, app.AlarmTable, app.AlarmThresholdsTable);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.StartButton.Text == "Start"
                app.MonitorOn = true;
                app.StartButton.Text = "Stop";
                app.StartButton.BackgroundColor = "0.94,0.09,0.09";
                %app.progressbar = uiprogressdlg(app.UIFigure,'Title','Please wait...',...
                %    'Indeterminate','on');
                %app.SetUpConnection();
                start(app.DataTimer);
                
                %close(app.progressbar);
      
            else
                app.StartButton.Text = "Start";
                app.StartButton.BackgroundColor = "0.47,0.67,0.19";
                stop(app.DataTimer);
                %app.StopMonitor();
                app.TrendsButton.Enable = true;
                
                app.MonitorOn = false;
                CheckAlarmValues(app);
            end
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete all apps
            delete(app.SaveDialog)
            delete(app.EditThresholdDialog)
            delete(app)
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            % Open EditThresholdApp
            app.EditButton.Enable = false;
            app.EditThresholdDialog = EditThresholdApp(app, app.MinEditLimitHR.Value, app.MaxEditLimitHR.Value, app.MinEditLimitSpO2.Value, app.MaxEditLimitSpO2.Value, app.AlarmThresholdsTable, app.kk);
            %app.kk=app.kk+1;
            disp(app.AlarmThresholdsTable)
        end

        % Value changed function: MuteAlarm
        function MuteAlarmValueChanged(app, event)
            app.MuteAlarm.Enable = false;
            app.Image_AlarmOn.Visible = false;
            app.Image_AlarmPaused.Visible = true;
            pause(10); % alarm is paused 10 sek.
            app.MuteAlarm.Value = false;
            app.MuteAlarm.Enable = true;
            app.Image_AlarmOn.Visible = app.soundAlarm;
            app.Image_AlarmPaused.Visible = false;
            
        end

        % Button pushed function: alarmButton
        function alarmButtonPushed(app, event)
            stop(app.DataTimer);
            %r = randi([100,157],1,2);
            app.HR_value_label.Text = '155';
            app.SpO2_value_label.Text = '97'; %num2str(r(2));
            drawnow % this updates the GUI immediatly at every iteration
            app.SpO2data(app.SpO2Count) = 97;
            app.plotSpO2();
            CheckAlarmValues(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            start(app.DataTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = {'HR'; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'greenHeartNew.png';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.HR_value_label = uilabel(app.UIFigure);
            app.HR_value_label.Text = '100';
            app.SpO2Label = uilabel(app.UIFigure);
            app.SpO2Label.Text = 'SpO2';
            app.PercentageLabel = uilabel(app.UIFigure);
            app.PercentageLabel.Text = '%';
            app.SpO2_value_label = uilabel(app.UIFigure);
            app.SpO2_value_label.Text = '100';
            app.StartStopPanel = uipanel(app.UIFigure);
            app.StartStopPanel.Title = 'Start/Stop';
            app.StartButton = uibutton(app.StartStopPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TrendsButton = uibutton(app.StartStopPanel, 'push');
            app.TrendsButton.ButtonPushedFcn = createCallbackFcn(app, @TrendsButtonPushed, true);
            app.TrendsButton.Icon = 'pngwing.com.png';
            app.TrendsButton.Enable = 'off';
            app.TrendsButton.Text = 'Trends';
            app.AlarmThresholdsPanel = uipanel(app.UIFigure);
            app.AlarmThresholdsPanel.Title = 'Alarm Thresholds';
            app.SpO2Panel = uipanel(app.AlarmThresholdsPanel);
            app.SpO2Panel.Title = 'SpO2';
            app.MinEditFieldLabel_2 = uilabel(app.SpO2Panel);
            app.MinEditFieldLabel_2.Text = 'Min';
            app.MinEditLimitSpO2 = uieditfield(app.SpO2Panel, 'numeric');
            app.MinEditLimitSpO2.Editable = 'off';
            app.MinEditLimitSpO2.Value = 85;
            app.MaxLabel_5 = uilabel(app.SpO2Panel);
            app.MaxLabel_5.Text = 'Max';
            app.MaxEditLimitSpO2 = uieditfield(app.SpO2Panel, 'numeric');
            app.MaxEditLimitSpO2.Editable = 'off';
            app.MaxEditLimitSpO2.Value = 100;
            app.HRPanel = uipanel(app.AlarmThresholdsPanel);
            app.HRPanel.Title = 'HR';
            app.MaxLabel_4 = uilabel(app.HRPanel);
            app.MaxLabel_4.Text = 'Max';
            app.MaxEditLimitHR = uieditfield(app.HRPanel, 'numeric');
            app.MaxEditLimitHR.Editable = 'off';
            app.MaxEditLimitHR.Value = 150;
            app.MinEditFieldLabel = uilabel(app.HRPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditLimitHR = uieditfield(app.HRPanel, 'numeric');
            app.MinEditLimitHR.Editable = 'off';
            app.MinEditLimitHR.Value = 65;
            app.EditButton = uibutton(app.AlarmThresholdsPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.Image_AlarmOn = uiimage(app.UIFigure);
            app.Image_AlarmOn.ImageSource = 'Alarm_billede2.PNG';
            app.Image_AlarmPaused = uiimage(app.UIFigure);
            app.Image_AlarmPaused.ImageSource = 'Alarm_billede1.PNG';
            app.MuteAlarm = uibutton(app.UIFigure, 'state');
            app.MuteAlarm.ValueChangedFcn = createCallbackFcn(app, @MuteAlarmValueChanged, true);
            app.MuteAlarm.Text = 'MUTE ALARM';
            app.ECGUIAxes = uiaxes(app.UIFigure);
            title(app.ECGUIAxes, '')
            xlabel(app.ECGUIAxes, '')
            ylabel(app.ECGUIAxes, '')
            app.ECGUIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SpO2UIAxes = uiaxes(app.UIFigure);
            title(app.SpO2UIAxes, '')
            xlabel(app.SpO2UIAxes, '')
            ylabel(app.SpO2UIAxes, '')
            app.SpO2UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MinEditLimitHR_2 = uieditfield(app.UIFigure, 'numeric');
            app.MinEditLimitHR_2.ValueDisplayFormat = '%.0f';
            app.MinEditLimitHR_2.Editable = 'off';
            app.MinEditLimitHR_2.Value = 65;
            app.MaxEditLimitHR_2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditLimitHR_2.Editable = 'off';
            app.MaxEditLimitHR_2.Value = 150;
            app.MinEditLimitSpO2_2 = uieditfield(app.UIFigure, 'numeric');
            app.MinEditLimitSpO2_2.Editable = 'off';
            app.MinEditLimitSpO2_2.Value = 85;
            app.MaxEditLimitSpO2_2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditLimitSpO2_2.Editable = 'off';
            app.MaxEditLimitSpO2_2.Value = 100;
            app.EditthresholdButton = uibutton(app.UIFigure, 'push');
            app.EditthresholdButton.Text = 'Edit threshold';
            app.StartStopPanel_2 = uipanel(app.UIFigure);
            app.StartStopPanel_2.Title = 'Start/Stop';
            app.StartButton_2 = uibutton(app.StartStopPanel_2, 'push');
            app.StartButton_2.Text = 'Start';
            app.TrendsButton_2 = uibutton(app.StartStopPanel_2, 'push');
            app.TrendsButton_2.Icon = 'pngwing.com.png';
            app.TrendsButton_2.Enable = 'off';
            app.TrendsButton_2.Text = 'Trends';
            app.alarmButton = uibutton(app.UIFigure, 'push');
            app.alarmButton.ButtonPushedFcn = createCallbackFcn(app, @alarmButtonPushed, true);
            app.alarmButton.Text = 'alarm';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_MonitorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end