classdef EditAlarmThresholdApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        HRPanel              matlab.ui.container.Panel
        HRmin_value_label    matlab.ui.control.Label
        Slider_HRmin         matlab.ui.control.Slider
        MinLabel             matlab.ui.control.Label
        HRmin_up_B           matlab.ui.control.Button
        HRmin_down_B         matlab.ui.control.Button
        BPMLabel             matlab.ui.control.Label
        Slider_HRmax         matlab.ui.control.Slider
        HRmax_value_label    matlab.ui.control.Label
        MaxLabel             matlab.ui.control.Label
        HRmax_up_B           matlab.ui.control.Button
        HRmax_down_B         matlab.ui.control.Button
        BPMLabel_2           matlab.ui.control.Label
        OKButton             matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        SpO2Panel            matlab.ui.container.Panel
        SpO2min_value_label  matlab.ui.control.Label
        Slider_SpO2min       matlab.ui.control.Slider
        MinLabel_2           matlab.ui.control.Label
        SpO2min_up_B         matlab.ui.control.Button
        SpO2min_down_B       matlab.ui.control.Button
        PercentageLabel_2    matlab.ui.control.Label
        MaxLabel_2           matlab.ui.control.Label
        Slider_SpO2max       matlab.ui.control.Slider
        PercentageLabel      matlab.ui.control.Label
        SpO2max_value_label  matlab.ui.control.Label
        SpO2max_up_B         matlab.ui.control.Button
        SpO2max_donw_B       matlab.ui.control.Button
    end

    
    properties (Access = private)
        MonitorAppDialog   % Main app objekt
        ThresholdValues
        
        
    end
    
    properties (Access = public)
        Counter = 1;            % counter for alarmThresholds id in table
        AlarmThresholdsTable
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, HRmin, HRmax, SpO2min, SpO2max, alarmthresholdtable, kk)
            app.UIFigure.Name = "Edit Alarm Thresholds";
            
            % Store main app in property for CloseRequestFcn to use
            app.MonitorAppDialog = mainapp;
            
%             % set limits for the sliders
            app.Slider_HRmin.Limits = [50 350];
            app.Slider_HRmax.Limits = [50 350];
            app.Slider_SpO2min.Limits = [50 100];
            app.Slider_SpO2max.Limits = [50 100];          
            
            % sets the label value
            app.HRmin_value_label.Text = num2str(HRmin);
            app.HRmax_value_label.Text =  num2str(HRmax);
            app.SpO2min_value_label.Text = num2str(SpO2min);
            app.SpO2max_value_label.Text = num2str(SpO2max);
            
            % sets the sliders value
            app.Slider_HRmin.Value = HRmin;
            app.Slider_HRmax.Value = HRmax;
            app.Slider_SpO2min.Value = SpO2min;
            app.Slider_SpO2max.Value = SpO2max;

            app.AlarmThresholdsTable = alarmthresholdtable;
            app.Counter = kk;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % set new Limits values on Monitor
            app.MonitorAppDialog.MinEditLimitHR.Value = round(app.Slider_HRmin.Value);
            app.MonitorAppDialog.MaxEditLimitHR.Value = round(app.Slider_HRmax.Value);
            app.MonitorAppDialog.MinEditLimitSpO2.Value = round(app.Slider_SpO2min.Value);
            app.MonitorAppDialog.MaxEditLimitSpO2.Value = round(app.Slider_SpO2max.Value);
            
            app.MonitorAppDialog.EditButton.Enable = true;
            
            % Save the new values in table
            UpdateAlarmThresholdsTable(app,app.Counter,str2double(app.HRmin_value_label.Text), str2double(app.HRmax_value_label.Text), str2double(app.SpO2min_value_label.Text), str2double(app.SpO2max_value_label.Text),datetime('now','Format','HH:mm'));
            app.MonitorAppDialog.AlarmThresholdsTable = app.AlarmThresholdsTable;
            
            % add 1 to counter
            app.MonitorAppDialog.Counter_alarmThresholds = app.MonitorAppDialog.Counter_alarmThresholds+1;
            
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MonitorAppDialog.EditButton.Enable = true;
            delete(app);           
            
        end

        % Button pushed function: HRmax_down_B
        function HRmax_down_BButtonPushed(app, event)
            app.Slider_HRmax.Value = round(app.Slider_HRmax.Value) - 1;
            app.HRmax_value_label.Text = num2str(app.Slider_HRmax.Value);
        end

        % Button pushed function: HRmax_up_B
        function HRmax_up_BButtonPushed(app, event)
            app.Slider_HRmax.Value = round(app.Slider_HRmax.Value) + 1;
            app.HRmax_value_label.Text = num2str(app.Slider_HRmax.Value);
        end

        % Button pushed function: SpO2min_down_B
        function SpO2min_down_BButtonPushed(app, event)
            app.Slider_SpO2min.Value = round(app.Slider_SpO2min.Value) - 1;
            app.SpO2min_value_label.Text = num2str(app.Slider_SpO2min.Value);
        end

        % Button pushed function: SpO2min_up_B
        function SpO2min_up_BButtonPushed(app, event)
            app.Slider_SpO2min.Value = round(app.Slider_SpO2min.Value) + 1;
            app.SpO2min_value_label.Text = num2str(app.Slider_SpO2min.Value);
        end

        % Button pushed function: SpO2max_donw_B
        function SpO2max_donw_BButtonPushed(app, event)
            app.Slider_SpO2max.Value = round(app.Slider_SpO2max.Value) - 1;
            app.SpO2max_value_label.Text = num2str(app.Slider_SpO2max.Value);
        end

        % Button pushed function: SpO2max_up_B
        function SpO2max_up_BButtonPushed(app, event)
            app.Slider_SpO2max.Value = round(app.Slider_SpO2max.Value) + 1;
            app.SpO2max_value_label.Text = num2str(app.Slider_SpO2max.Value);
        end

        % Button pushed function: HRmin_down_B
        function HRmin_down_BButtonPushed(app, event)
            app.Slider_HRmin.Value = round(app.Slider_HRmin.Value) - 1;
            app.HRmin_value_label.Text = num2str(app.Slider_HRmin.Value);
        end

        % Button pushed function: HRmin_up_B
        function HRmin_up_BButtonPushed(app, event)
            app.Slider_HRmin.Value = round(app.Slider_HRmin.Value) + 1;
            app.HRmin_value_label.Text = num2str(app.Slider_HRmin.Value);
        end

        % Value changing function: Slider_SpO2max
        function Slider_SpO2maxValueChanging(app, event)
            app.SpO2max_value_label.Text = num2str(round(event.Value));
            
        end

        % Value changing function: Slider_SpO2min
        function Slider_SpO2minValueChanging(app, event)
            app.SpO2min_value_label.Text = num2str(round(event.Value));
            
        end

        % Value changing function: Slider_HRmax
        function Slider_HRmaxValueChanging(app, event)
            app.HRmax_value_label.Text = num2str(round(event.Value));
            
        end

        % Value changing function: Slider_HRmin
        function Slider_HRminValueChanging(app, event)
            app.HRmin_value_label.Text = num2str(round(event.Value));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MonitorAppDialog.EditButton.Enable = true;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HRPanel = uipanel(app.UIFigure);
            app.HRPanel.Title = 'HR';
            app.HRmin_value_label = uilabel(app.HRPanel);
            app.HRmin_value_label.Text = '60';
            app.Slider_HRmin = uislider(app.HRPanel);
            app.Slider_HRmin.Limits = [50 350];
            app.Slider_HRmin.ValueChangingFcn = createCallbackFcn(app, @Slider_HRminValueChanging, true);
            app.Slider_HRmin.Value = 50;
            app.MinLabel = uilabel(app.HRPanel);
            app.MinLabel.Text = 'Min';
            app.HRmin_up_B = uibutton(app.HRPanel, 'push');
            app.HRmin_up_B.ButtonPushedFcn = createCallbackFcn(app, @HRmin_up_BButtonPushed, true);
            app.HRmin_up_B.Icon = 'up-arrow-icon.png';
            app.HRmin_up_B.Text = '';
            app.HRmin_down_B = uibutton(app.HRPanel, 'push');
            app.HRmin_down_B.ButtonPushedFcn = createCallbackFcn(app, @HRmin_down_BButtonPushed, true);
            app.HRmin_down_B.Icon = 'down-arrow-icon .png';
            app.HRmin_down_B.Text = '';
            app.BPMLabel = uilabel(app.HRPanel);
            app.BPMLabel.Text = 'BPM';
            app.Slider_HRmax = uislider(app.HRPanel);
            app.Slider_HRmax.Limits = [50 350];
            app.Slider_HRmax.ValueChangingFcn = createCallbackFcn(app, @Slider_HRmaxValueChanging, true);
            app.Slider_HRmax.Value = 50;
            app.HRmax_value_label = uilabel(app.HRPanel);
            app.HRmax_value_label.Text = '180';
            app.MaxLabel = uilabel(app.HRPanel);
            app.MaxLabel.Text = 'Max';
            app.HRmax_up_B = uibutton(app.HRPanel, 'push');
            app.HRmax_up_B.ButtonPushedFcn = createCallbackFcn(app, @HRmax_up_BButtonPushed, true);
            app.HRmax_up_B.Icon = 'up-arrow-icon.png';
            app.HRmax_up_B.Text = '';
            app.HRmax_down_B = uibutton(app.HRPanel, 'push');
            app.HRmax_down_B.ButtonPushedFcn = createCallbackFcn(app, @HRmax_down_BButtonPushed, true);
            app.HRmax_down_B.Icon = 'down-arrow-icon .png';
            app.HRmax_down_B.Text = '';
            app.BPMLabel_2 = uilabel(app.HRPanel);
            app.BPMLabel_2.Text = 'BPM';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SpO2Panel = uipanel(app.UIFigure);
            app.SpO2Panel.Title = 'SpO2';
            app.SpO2min_value_label = uilabel(app.SpO2Panel);
            app.SpO2min_value_label.Text = '84';
            app.Slider_SpO2min = uislider(app.SpO2Panel);
            app.Slider_SpO2min.Limits = [50 100];
            app.Slider_SpO2min.MajorTicks = [50 53 56 59 62 65 68 71 74 77 80 83 86 89 92 95 98 100];
            app.Slider_SpO2min.MajorTickLabels = {'50', '53', '56', '59', '62', '65', '68', '71', '74', '77', '80', '83', '86', '89', '92', '95', '98  ', '100'};
            app.Slider_SpO2min.ValueChangingFcn = createCallbackFcn(app, @Slider_SpO2minValueChanging, true);
            app.Slider_SpO2min.Value = 50;
            app.MinLabel_2 = uilabel(app.SpO2Panel);
            app.MinLabel_2.Text = 'Min';
            app.SpO2min_up_B = uibutton(app.SpO2Panel, 'push');
            app.SpO2min_up_B.ButtonPushedFcn = createCallbackFcn(app, @SpO2min_up_BButtonPushed, true);
            app.SpO2min_up_B.Icon = 'up-arrow-icon.png';
            app.SpO2min_up_B.Text = '';
            app.SpO2min_down_B = uibutton(app.SpO2Panel, 'push');
            app.SpO2min_down_B.ButtonPushedFcn = createCallbackFcn(app, @SpO2min_down_BButtonPushed, true);
            app.SpO2min_down_B.Icon = 'down-arrow-icon .png';
            app.SpO2min_down_B.Text = '';
            app.PercentageLabel_2 = uilabel(app.SpO2Panel);
            app.PercentageLabel_2.Text = '%';
            app.MaxLabel_2 = uilabel(app.SpO2Panel);
            app.MaxLabel_2.Text = 'Max';
            app.Slider_SpO2max = uislider(app.SpO2Panel);
            app.Slider_SpO2max.Limits = [50 100];
            app.Slider_SpO2max.MajorTicks = [50 53 56 59 62 65 68 71 74 77 80 83 86 89 92 95 98 100];
            app.Slider_SpO2max.MajorTickLabels = {'50', '53', '56', '59', '62', '65', '68', '71', '74', '77', '80', '83', '86', '89', '92', '95', '98  ', '100'};
            app.Slider_SpO2max.ValueChangingFcn = createCallbackFcn(app, @Slider_SpO2maxValueChanging, true);
            app.Slider_SpO2max.Value = 50;
            app.PercentageLabel = uilabel(app.SpO2Panel);
            app.PercentageLabel.Text = '%';
            app.SpO2max_value_label = uilabel(app.SpO2Panel);
            app.SpO2max_value_label.Text = '100';
            app.SpO2max_up_B = uibutton(app.SpO2Panel, 'push');
            app.SpO2max_up_B.ButtonPushedFcn = createCallbackFcn(app, @SpO2max_up_BButtonPushed, true);
            app.SpO2max_up_B.Icon = 'up-arrow-icon.png';
            app.SpO2max_up_B.Text = '';
            app.SpO2max_donw_B = uibutton(app.SpO2Panel, 'push');
            app.SpO2max_donw_B.ButtonPushedFcn = createCallbackFcn(app, @SpO2max_donw_BButtonPushed, true);
            app.SpO2max_donw_B.Icon = 'down-arrow-icon .png';
            app.SpO2max_donw_B.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditAlarmThresholdApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end