classdef MonitorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HRLabel               matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        BPMLabel              matlab.ui.control.Label
        HR_value_label        matlab.ui.control.Label
        SpO2Label             matlab.ui.control.Label
        PercentageLabel       matlab.ui.control.Label
        SpO2_value_label      matlab.ui.control.Label
        TempLabel             matlab.ui.control.Label
        CLabel                matlab.ui.control.Label
        Temp_value_label      matlab.ui.control.Label
        StartStopPanel        matlab.ui.container.Panel
        StartButton           matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
        AlarmThresholdsPanel  matlab.ui.container.Panel
        SpO2Panel             matlab.ui.container.Panel
        MinEditFieldLabel_2   matlab.ui.control.Label
        MinEditLimitSpO2      matlab.ui.control.NumericEditField
        MaxLabel_5            matlab.ui.control.Label
        MaxEditLimitSpO2      matlab.ui.control.NumericEditField
        HRPanel               matlab.ui.container.Panel
        MinEditFieldLabel     matlab.ui.control.Label
        MinEditLimitHR        matlab.ui.control.NumericEditField
        MaxLabel_4            matlab.ui.control.Label
        MaxEditLimitHR        matlab.ui.control.NumericEditField
        TempPanel             matlab.ui.container.Panel
        MinEditFieldLabel_3   matlab.ui.control.Label
        MinEditLimitTemp      matlab.ui.control.NumericEditField
        MaxLabel_6            matlab.ui.control.Label
        MaxEditLimitTemp      matlab.ui.control.NumericEditField
        EditButton            matlab.ui.control.Button
        MutealarmButton       matlab.ui.control.Button
        Image_AlarmOn         matlab.ui.control.Image
        Image_AlarmPaused     matlab.ui.control.Image
        StopAlarmButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        SaveDialog                   % Dialog box app
        DataTable                    % table for data
        CurrentHR
        CurrentSpO2
        CurrentTemp
        AlarmState
        DataTimer                    % Timer for reading data from arduino
        S_connection                 % Serial object for dataprocessing
        progressbar                  % Feedback to user
        timer_alarmAudio             % timer for playing alarm audio
        timer_alarmFlash             % timer for flashing label when alarming
        Alarm_HR                     % Alarm state for HR
        Alarm_SpO2                   % Alarm state for SpO2
        Alarm_temp                   % Alarm state for temp
        AlarmAudioMuted = false      % used when user press the mute button
        
    end
    
    properties (Access = public)
        MeasureValueLabel = cell(3,1); % Property for which label there should flash
        player                         % player for the alarm sound
        MeasureTable                   % Table for measure values
        AlarmTable                     % Table for alarm values
        ii = 1;                        % counter for alarm id in table
        jj = 1;                        % counter for alarm id in table
    end


    methods (Access = public) % public funktion som kan kaldes fra andre Apps
        
        function Test_update(app, x) % skal denne slettes??
            app.MinEditLimitHR.Text = x;
        end
        
        function CheckAlarmValues(app)
            app.MeasureValueLabel = cell(3,1); % nulstilles Label
            
            % OBS på om vi bruger current propertie?? ellers skal der tjekkes på hvilke værdier der vises på GUI
            app.CurrentHR = str2double(app.HR_value_label.Text);
            app.CurrentSpO2 = str2double(app.SpO2_value_label.Text);
            
            % Checks all 3 mesasure values
            if app.CurrentHR > app.MaxEditLimitHR.Value || app.CurrentHR< app.MinEditLimitHR.Value
                app.Alarm_HR = true;
                app.MeasureValueLabel{1} = app.HR_value_label;
                
                %Gemmer alarm ned i tabel
                UpdateAlarmTable(app, app.ii, "HR", app.CurrentHR, app.MinEditLimitHR.Value, app.MaxEditLimitHR.Value, now());
                app.ii=app.ii+1; %% tæller op
            else
                app.Alarm_HR = false;
            end
            if all(app.CurrentSpO2 > app.MaxEditLimitSpO2.Value) || all(app.CurrentSpO2 < app.MinEditLimitSpO2.Value)
                app.Alarm_SpO2 = true;
                app.MeasureValueLabel{2} = app.SpO2_value_label;
                
                %Gemmer alarm ned i tabel
                UpdateAlarmTable(app, app.ii, "SpO2", app.CurrentSpO2, app.MinEditLimitSpO2.Value, app.MaxEditLimitSpO2.Value, now());
                app.ii=app.ii+1; %% tæller op
            else
                app.Alarm_SpO2 = false;
            end

            
            % if just one measure value is critical alarm should start
            if app.Alarm_HR || app.Alarm_SpO2 
                StartAlarm(app);
            end
            % if just one of the measure values is OK, stop alarm is
            % called, so the label can be updated
            if ~app.Alarm_HR || ~app.Alarm_SpO2
                StopAlarm(app);
            end
        end
    end
    
    methods (Access = private)


        function StartAlarm(app)
            
            if strcmp(app.timer_alarmAudio.Running, 'off') % if timer for alarm audio is off
                if ~app.AlarmAudioMuted % run if the alarm is not muted
                    app.Image_AlarmOn.Visible = true; % show symbol
                    start(app.timer_alarmAudio);
                    
                    % Show button for mute alarm
                    app.MutealarmButton.Visible = true;
                    app.MutealarmButton.Enable = true;
                    
                    % MANGLER -> indsæt værdier i tabel
                end
            end
            % update the alarm flash
            stop(app.timer_alarmFlash);
            app.timer_alarmFlash.TimerFcn = {@flashAlarmLabel, app, app.MeasureValueLabel};
            start(app.timer_alarmFlash);
            
        end
        
        function StopAlarm(app)
            
            % only runs if the alarm is on
            if strcmp(app.timer_alarmAudio.Running, 'on') % If alarm audio is on
                
                % stops only the alarm audio and hides the symbol if no alarmStates is true
                if ~app.Alarm_HR && ~app.Alarm_SpO2 && ~app.Alarm_temp
                    app.Image_AlarmOn.Visible = false;
                    app.Image_AlarmPaused.Visible = false;
                    stop(app.timer_alarmAudio);
                    app.MutealarmButton.Enable = false;
                    app.MutealarmButton.Visible = false;
                    stop(app.timer_alarmFlash);
                else
                    % updates the alarm flash
                    stop(app.timer_alarmFlash);
                    app.timer_alarmFlash.TimerFcn = {@flashAlarmLabel, app, app.MeasureValueLabel};
                    start(app.timer_alarmFlash);
                end
                
                % Set labels back to the right color
                if ~app.Alarm_HR
                    app.HR_value_label.FontColor = "0.39,0.83,0.07";
                    app.HR_value_label.Visible = 'on';
                end
                if ~app.Alarm_SpO2
                    app.SpO2_value_label.FontColor = "0.07,0.62,1.00";
                    app.SpO2_value_label.Visible = 'on';
                end
                if ~app.Alarm_temp
                    app.Temp_value_label.FontColor = "1.00,1.00,0.00";
                    app.Temp_value_label.Visible = 'on';
                end
            end
            
            
        end
        
        function SetUpConnection(app)
            % Setup serial connection
            comPort = 'COM3';
            app.S_connection = serial(comPort,'BaudRate',115200);
            
            % Open connection
            fopen(app.S_connection);
            disp('Serial connection is open...')
        end
        
        function StopMonitor(app)
%             stop(app.timer_alarmFlash);
%             stop(app.timer_alarmAudio);
            fclose(app.S_connection);
            delete(app.S_connection);
            disp('Serial Connection is closed')
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.SetUpConnection(); % set up connection to arduino
            
            % Creating timer for getting measurement values
            app.DataTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                "Period", .2, ... % OBS!!!! Change value to how often the gui should update
                "BusyMode", "queue", ...
                "TimerFcn", {@getPulseSpO2, app, app.S_connection,app.HR_value_label,app.SpO2_value_label});
            
            % Create timer class for flashing label
            app.timer_alarmFlash = timer(...
                'ExecutionMode', 'fixedRate', ...
                "Period", 0.50, ...
                "BusyMode", "queue", ...
                "TimerFcn", {@flashAlarmLabel, app, app.MeasureValueLabel}); %both app and app.Label must be inputs for the function has access to app.Label
            
            % load melody for alarm audio
            [y, Fs] = audioread('alarmMedPause.wav');
            app.player = audioplayer(y, Fs);
            
            % Create timer class for playing alarm audio
            app.timer_alarmAudio = timer(...
                'ExecutionMode', 'fixedRate', ...
                "Period", 0.1, ...
                "BusyMode", "queue", ...
                "TimerFcn", {@audioAlarm, app, app.player});
            
            %Create table for alarm values
            sz = [500 6];
            alarm_varTypes = {'double','string','string','double','double','double'};
            alarm_varNames = {'ID','MeasureType','MeasureValue','ThresholdMin','ThresholdMax','Time'};
            app.AlarmTable = table('Size',sz,'VariableTypes',alarm_varTypes,'VariableNames',alarm_varNames);
            
            %Create table for measure values
            sz = [1000 4];
            measure_varTypes = {'double','double','double','double'};
            measure_varNames = {'ID','HR','SpO2','Time'};
            app.MeasureTable = table('Size',sz,'VariableTypes',measure_varTypes,'VariableNames',measure_varNames);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Her skal stå kode for når der trykkes på gem knappen
            
            %Test for at Gemme noget random kode ned i tabel
            % app.DataTable = "d";
            
            % Åbner SaveDialogApp
            app.SaveDialog = SaveDialogApp(app, app.DataTable);
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.StartButton.Text == "Start"
                app.StartButton.Text = "Stop";
                app.StartButton.BackgroundColor = "0.94,0.09,0.09";
                %app.progressbar = uiprogressdlg(app.UIFigure,'Title','Please wait...',...
                  %  'Indeterminate','on');
               app.SetUpConnection();
               app.DataTimer.TimerFcn = {@getPulseSpO2, app, app.S_connection,app.HR_value_label,app.SpO2_value_label};
                start(app.DataTimer);
                %close(app.progressbar)
                %app.CheckAlarmValues();
                
            else
                app.StartButton.Text = "Start";
                app.StartButton.BackgroundColor = "0.47,0.67,0.19";
                stop(app.DataTimer);
                app.StopMonitor();
            end
            
            
        end

        % Button pushed function: MutealarmButton
        function MutealarmButtonPushed(app, event)
            
            app.AlarmAudioMuted = true;
            app.MutealarmButton.Enable = false;
            stop(app.timer_alarmAudio);
            app.Image_AlarmOn.Visible = false;
            app.Image_AlarmPaused.Visible = true;
            pause(8);
            %if timer for alarm audio is off -> if there in the
            %mean time has ben registered an alarm for a measure value,
            if strcmp(app.timer_alarmAudio.Running, 'off')
                start(app.timer_alarmAudio);
            end
            app.Image_AlarmOn.Visible = true;
            app.Image_AlarmPaused.Visible = false;
            app.MutealarmButton.Enable = true;
            app.AlarmAudioMuted = false;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete all apps
            delete(app.SaveDialog)
            delete(app)
            
        end

        % Button pushed function: StopAlarmButton
        function StopAlarmButtonPushed(app, event)
            stop(app.timer_alarmFlash);
            stop(app.timer_alarmAudio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = {'HR'; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'image_processing20200511-27178-yzw8o8.png';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.HR_value_label = uilabel(app.UIFigure);
            app.HR_value_label.Text = '0';
            app.SpO2Label = uilabel(app.UIFigure);
            app.SpO2Label.Text = 'SpO2';
            app.PercentageLabel = uilabel(app.UIFigure);
            app.PercentageLabel.Text = '%';
            app.SpO2_value_label = uilabel(app.UIFigure);
            app.SpO2_value_label.Text = '0';
            app.TempLabel = uilabel(app.UIFigure);
            app.TempLabel.Text = 'Temp';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = '°C';
            app.Temp_value_label = uilabel(app.UIFigure);
            app.Temp_value_label.Text = '0';
            app.StartStopPanel = uipanel(app.UIFigure);
            app.StartStopPanel.Title = 'Start/Stop';
            app.StartButton = uibutton(app.StartStopPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.StartStopPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save-file.png';
            app.SaveButton.Text = 'Save';
            app.AlarmThresholdsPanel = uipanel(app.UIFigure);
            app.AlarmThresholdsPanel.Title = 'Alarm Thresholds';
            app.SpO2Panel = uipanel(app.AlarmThresholdsPanel);
            app.SpO2Panel.Title = 'SpO2';
            app.MinEditFieldLabel_2 = uilabel(app.SpO2Panel);
            app.MinEditFieldLabel_2.Text = 'Min';
            app.MinEditLimitSpO2 = uieditfield(app.SpO2Panel, 'numeric');
            app.MinEditLimitSpO2.Editable = 'off';
            app.MinEditLimitSpO2.Value = 85;
            app.MaxLabel_5 = uilabel(app.SpO2Panel);
            app.MaxLabel_5.Text = 'Max';
            app.MaxEditLimitSpO2 = uieditfield(app.SpO2Panel, 'numeric');
            app.MaxEditLimitSpO2.Editable = 'off';
            app.MaxEditLimitSpO2.Value = 90;
            app.HRPanel = uipanel(app.AlarmThresholdsPanel);
            app.HRPanel.Title = 'HR';
            app.MinEditFieldLabel = uilabel(app.HRPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditLimitHR = uieditfield(app.HRPanel, 'numeric');
            app.MinEditLimitHR.Editable = 'off';
            app.MinEditLimitHR.Value = 100;
            app.MaxLabel_4 = uilabel(app.HRPanel);
            app.MaxLabel_4.Text = 'Max';
            app.MaxEditLimitHR = uieditfield(app.HRPanel, 'numeric');
            app.MaxEditLimitHR.Editable = 'off';
            app.MaxEditLimitHR.Value = 180;
            app.TempPanel = uipanel(app.AlarmThresholdsPanel);
            app.TempPanel.Title = 'Temp';
            app.MinEditFieldLabel_3 = uilabel(app.TempPanel);
            app.MinEditFieldLabel_3.Text = 'Min';
            app.MinEditLimitTemp = uieditfield(app.TempPanel, 'numeric');
            app.MinEditLimitTemp.Editable = 'off';
            app.MinEditLimitTemp.Value = 36.5;
            app.MaxLabel_6 = uilabel(app.TempPanel);
            app.MaxLabel_6.Text = 'Max';
            app.MaxEditLimitTemp = uieditfield(app.TempPanel, 'numeric');
            app.MaxEditLimitTemp.Editable = 'off';
            app.MaxEditLimitTemp.Value = 37.5;
            app.EditButton = uibutton(app.AlarmThresholdsPanel, 'push');
            app.EditButton.Text = 'Edit';
            app.MutealarmButton = uibutton(app.UIFigure, 'push');
            app.MutealarmButton.ButtonPushedFcn = createCallbackFcn(app, @MutealarmButtonPushed, true);
            app.MutealarmButton.Enable = 'off';
            app.MutealarmButton.Visible = 'off';
            app.MutealarmButton.Text = 'MUTE ALARM';
            app.Image_AlarmOn = uiimage(app.UIFigure);
            app.Image_AlarmOn.Visible = 'off';
            app.Image_AlarmOn.ImageSource = 'Alarm_billede2.PNG';
            app.Image_AlarmPaused = uiimage(app.UIFigure);
            app.Image_AlarmPaused.Visible = 'off';
            app.Image_AlarmPaused.ImageSource = 'Alarm_billede1.PNG';
            app.StopAlarmButton = uibutton(app.UIFigure, 'push');
            app.StopAlarmButton.ButtonPushedFcn = createCallbackFcn(app, @StopAlarmButtonPushed, true);
            app.StopAlarmButton.Text = 'Stop Alarm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonitorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end