classdef MonitorMeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ECGUIAxes         matlab.ui.control.UIAxes
        SpO2UIAxes        matlab.ui.control.UIAxes
        MuteAlarm         matlab.ui.control.StateButton
        EditButton        matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        TrendsButton      matlab.ui.control.Button
        HRLabel           matlab.ui.control.Label
        Image_heart       matlab.ui.control.Image
        BPMLabel          matlab.ui.control.Label
        HR_value_label    matlab.ui.control.Label
        SpO2Label         matlab.ui.control.Label
        PercentageLabel   matlab.ui.control.Label
        SpO2_value_label  matlab.ui.control.Label
        MinEditLimitHR    matlab.ui.control.NumericEditField
        MaxEditLimitHR    matlab.ui.control.NumericEditField
        MinEditLimitSpO2  matlab.ui.control.NumericEditField
        MaxEditLimitSpO2  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        SaveDialog                   % Dialog box app
        EditThresholdDialog          % Dialog for edit threshold
        CurrentHR
        CurrentSpO2
        CurrentTemp
        AlarmState
        DataTimer                    % Timer for reading data from arduino
        S_connection                 % Serial object for dataprocessing
        Progressbar                  % Feedback to user
        LastAlarm = tic              % Timestamp for last alarm blink
        MonitorOn = false             
        Player                       % alarm sound
        SoundAlarm = false           % Boolean for if the alarm sound should be on
        ECGData = zeros(1,500)
        ECGPosition = 1;             % The position in the ECGData array - acts like a 'counter'
        SpO2Position = 1;            % % The position in the SpO2Data array - acts like a 'counter'
        SpO2data = zeros(1,50);
        LastAlarmSound = tic         % Timestamp for last alarm audio played
        FingerDetected = false;      % Will be true first time finger is detected
    end
    
    properties (Access = public)
        MeasuredValuesTable            % Table for measure values
        AlarmTable                     % Table for alarm values
        AlarmThresholdsTable
        Counter_alarm = 1;             % counter for alarm id in table
        Counter_measure = 1;           % counter for measure id in table
        Counter_alarmThresholds = 1;   % counter for new alarm thresholds used in table
    end
    
    
    methods (Access = private)
        
        
        function SetUpConnection(app)
            % Show progressbar to inform that data processing is started
            app.Progressbar = uiprogressdlg(app.UIFigure,'Title','Please wait, while reading measurements',...
                    'Indeterminate','on');
                
            % Setup serial connection
            comPort = 'COM3';
            
            % Open connection
            app.S_connection = serialport(comPort,9600); % sets the baudrate to 9600
            disp('Serial connection is open...')
        end
        
        function StopMonitor(app)
            app.S_connection = []; % clears the serial connection
            disp('Serial Connection is closed')
        end
        
        function getMeasurements(app,~,~)
            % Processes the retrieved raw data 
            rawData = strtrim(readline(app.S_connection));
            rawData = split(rawData,':'); % splits data -> HR:SpO2:Conficence:Status:ECG[array]
            
            % Get pulse & SpO2
            HR_SpO2_Data = str2double(rawData(1:4)); 
            
            % Set measured values on GUI if confidence is over 95% and finger is detected (status==3)
            if HR_SpO2_Data(3)>= 95 && HR_SpO2_Data(4)==3
                app.FingerDetected = true;
                app.HR_value_label.Text = num2str(HR_SpO2_Data(1));
                app.SpO2_value_label.Text = num2str(HR_SpO2_Data(2));
                drawnow % this updates the GUI immediatly               
            else
                disp('Finger detection error. Try replace finger')
            end
            
            % Get incoming new ECG data and add it to ECG array
            NewECGData = split(rawData(5),',')'; % transvers: vector --> array
            NewECGData = str2double(NewECGData(1:end-1)); % removes the last delimeter in array
            app.ECGData(app.ECGPosition:app.ECGPosition + 9) = NewECGData;
                           

            % Plot measured values on GUI and check if an alarm should be raised
            if app.FingerDetected
                close(app.Progressbar)
                app.plotECG();
                app.plotSpO2();
                UpdateCurrentMeasurementsValues(app);
            end
            
        end
        
        function plotECG(app)
            app.ECGUIAxes.XLim = [0 length(app.ECGData)];
            app.ECGUIAxes.YLim = [150 600];
            plot(app.ECGUIAxes, app.ECGData,'color',[0.39,0.83,0.07]);
            xline(app.ECGUIAxes, app.ECGPosition + 9,'color',[0.8, 0.8, 0.8]);
            app.ECGPosition = app.ECGPosition + 10;
            
            if app.ECGPosition == length(app.ECGData) + 1
                app.ECGPosition = 1;
            end
        end
        
        function plotSpO2(app)
            app.SpO2data(app.SpO2Position) = str2double(app.SpO2_value_label.Text);
            plot(app.SpO2UIAxes, app.SpO2data,'color',[0.07,0.62,1.00]);
            ylim(app.SpO2UIAxes,[0 100]);
            xline(app.SpO2UIAxes,app.SpO2Position,'color',[0.8, 0.8, 0.8]);
            app.SpO2Position = app.SpO2Position + 1;
            
            if app.SpO2Position > length(app.SpO2data)
                app.SpO2Position = 1;
            end
        end
        
        function CheckAlarmValues(app)
            
            % Check if alarm is already on
            if app.HR_value_label.FontColor == [1,0,0]
                newAlarmHR = false;
            else
                newAlarmHR = true;
            end
            
            if app.SpO2_value_label.FontColor == [1,0,0]
                newAlarmSpO2 = false;
            else
                newAlarmSpO2 = true;
            end
            
            % Labelstate is set to flashing measure label by using tic/toc
            if toc(app.LastAlarm) > 2
                labelState = false;
                app.LastAlarm = tic;
            else
                labelState = true;
            end
            
            app.SoundAlarm = false; % reset
            
            % Check if the current mesasurements is inside/outside Limit
            % values, and start alarm
            if app.CurrentHR > app.MaxEditLimitHR.Value || app.CurrentHR < app.MinEditLimitHR.Value
                app.HR_value_label.FontColor = "1.00,0.00,0.00";    % red
                app.HR_value_label.Visible = labelState;            % flash
                app.SoundAlarm = true;
                
                if newAlarmHR
                    %Save alarm in table
                    UpdateAlarmTable(app, app.Counter_alarm, "HR", app.CurrentHR, app.MinEditLimitHR.Value, app.MaxEditLimitHR.Value, datetime('now','Format','HH:mm'));
                    app.Counter_alarm=app.Counter_alarm+1;          % update counter for the table
                end
            else
                % Set to normal
                app.HR_value_label.FontColor = "0.39,0.83,0.07";
                app.HR_value_label.Visible = 'on';
            end
            if app.CurrentSpO2 > app.MaxEditLimitSpO2.Value || app.CurrentSpO2 < app.MinEditLimitSpO2.Value
                app.SpO2_value_label.FontColor = "1.00,0.00,0.00";
                app.SpO2_value_label.Visible = labelState;
                app.SoundAlarm = true;
                
                if newAlarmSpO2
                    %Save alarm in table
                    UpdateAlarmTable(app, app.Counter_alarm, "SpO2", app.CurrentSpO2, app.MinEditLimitSpO2.Value, app.MaxEditLimitSpO2.Value, datetime('now','Format',"HH:mm"));
                    app.Counter_alarm=app.Counter_alarm+1;
                end
            else
                % Set to normal
                app.SpO2_value_label.FontColor = "0.07,0.62,1.00";
                app.SpO2_value_label.Visible = 'on';
            end
            
            % Check if alarm sound should be on
            UpdateAlarmSound(app);           
            
        end
        
        function UpdateAlarmSound(app)
            
            if app.SoundAlarm                
                if ~app.MuteAlarm.Value % is not played if the alarm is muted
                    if toc(app.LastAlarmSound) < 8 % alarm tone should be played with a break between each tone on 2,5-15 seconds  
                        %nothing --> break after one tone is played
                    else
                        play(app.Player);
                        app.LastAlarmSound = tic;
                    end                    
                    app.MuteAlarm.Visible = true;
                    app.MuteAlarm.Enable = true;
                end
                
            else                
                app.MuteAlarm.Enable = false;
                app.MuteAlarm.Visible = false;
            end
            
        end
        
        
        function UpdateCurrentMeasurementsValues(app)
            
            % update current measured values if the monitor is on
            if app.MonitorOn
                app.CurrentHR = str2double(app.HR_value_label.Text);
                app.CurrentSpO2 = str2double(app.SpO2_value_label.Text);
            else % if monitor is off, the current values is set, so the alarm should stop
                app.CurrentHR = app.MinEditLimitHR.Value;
                app.CurrentSpO2 = app.MinEditLimitSpO2.Value;
            end
            
            %Save measured values in table
            UpdateMeasuredValuesTable(app, app.Counter_measure, app.CurrentHR, app.CurrentSpO2, datetime('now','Format','HH:mm'));
            app.Counter_measure=app.Counter_measure+1; % counter for the table
            
            % Check if the new measurements should start an alarm
            CheckAlarmValues(app);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "MonitorMe";
            
            app.ECGUIAxes.XTick = [];
            app.ECGUIAxes.YTick = [];
            app.SpO2UIAxes.XTick = [];
            app.SpO2UIAxes.YTick = [];
            
            delete(timerfindall);
            
            % Creating timer for getting measurement values
            app.DataTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                "Period", 0.5, ... 
                "BusyMode", "drop", ... % There is no queue, it only gets data when something is available
                "Name", "DataTimer", ...
                "TimerFcn", {@app.getMeasurements});
            
            % load melody for alarm audio
            [y, Fs] = audioread('alarm10pulses.wav');
            app.Player = audioplayer(y, Fs);
            
            %Create table for alarm values
            sz = [500 6];
            alarm_varTypes = {'double','string','double','double','double','datetime'};
            alarm_varNames = {'ID','MeasureType','MeasureValue','ThresholdMin','ThresholdMax','Time'};
            app.AlarmTable = table('Size',sz,'VariableTypes',alarm_varTypes,'VariableNames',alarm_varNames);
            
            %Create table for measure values
            sz = [1000 4];
            measure_varTypes = {'double','double','double','datetime'};
            measure_varNames = {'ID','HR','SpO2','Time'};
            app.MeasuredValuesTable = table('Size',sz,'VariableTypes',measure_varTypes,'VariableNames',measure_varNames);
            
            %Create table for alarmthreshold values
            sz = [20 6];
            thresholds_varTypes = {'double','double','double','double','double','datetime'};
            thresholds_varNames = {'ID','minHR','maxHR','minSpO2','maxSpO2','TimeChanged'};
            app.AlarmThresholdsTable = table('Size',sz,'VariableTypes',thresholds_varTypes,'VariableNames',thresholds_varNames);
            
            
            UpdateAlarmThresholdsTable(app,app.Counter_alarmThresholds,app.MinEditLimitHR.Value, app.MaxEditLimitHR.Value, app.MinEditLimitSpO2.Value, app.MaxEditLimitSpO2.Value, datetime('now','Format','HH:mm'));
            app.Counter_alarmThresholds=app.Counter_alarmThresholds+1;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete all apps
            delete(app.SaveDialog)
            delete(app.EditThresholdDialog)
            delete(app)
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            % Open EditThresholdApp
            app.EditButton.Enable = false;
            app.EditThresholdDialog = EditAlarmThresholdApp(app, app.MinEditLimitHR.Value, app.MaxEditLimitHR.Value, app.MinEditLimitSpO2.Value, app.MaxEditLimitSpO2.Value, app.AlarmThresholdsTable, app.Counter_alarmThresholds);
            
            disp(app.AlarmThresholdsTable)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Change state of the button
            if app.StartButton.Text == "Start"
                app.MonitorOn = true;
                app.StartButton.Text = "Stop";
                app.StartButton.BackgroundColor = "0.94,0.09,0.09";
                app.SetUpConnection();
                start(app.DataTimer); % starts the timer fcn, which will get the measurement data continously every 0.5 sec                
            else
                app.StartButton.Text = "Start";
                app.StartButton.BackgroundColor = "0.47,0.67,0.19";
                stop(app.DataTimer);
                app.StopMonitor(); % stops the timer fcn
                app.TrendsButton.Enable = true;
                
                app.MonitorOn = false;
                CheckAlarmValues(app); % stops a potential alarm if activated
            end
        end

        % Button pushed function: TrendsButton
        function TrendsButtonPushed(app, event)
            % Åbner SaveDialogApp
            UpdateAlarmThresholdsTable(app,app.Counter_alarmThresholds,app.MinEditLimitHR.Value, app.MaxEditLimitHR.Value, app.MinEditLimitSpO2.Value, app.MaxEditLimitSpO2.Value, datetime('now','Format','HH:mm'));
            app.SaveDialog = TrendsAndSaveApp(app, app.MeasuredValuesTable, app.AlarmTable, app.AlarmThresholdsTable);
            app.TrendsButton.Enable = false;
        end

        % Value changed function: MuteAlarm
        function MuteAlarmValueChanged(app, event)
            app.MuteAlarm.Enable = false;            
            pause(40); % alarm is paused 40 sek.
            app.MuteAlarm.Value = false;
            app.MuteAlarm.Enable = true;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ECGUIAxes = uiaxes(app.UIFigure);
            title(app.ECGUIAxes, '')
            xlabel(app.ECGUIAxes, '')
            ylabel(app.ECGUIAxes, '')
            app.SpO2UIAxes = uiaxes(app.UIFigure);
            title(app.SpO2UIAxes, '')
            xlabel(app.SpO2UIAxes, '')
            ylabel(app.SpO2UIAxes, '')
            app.SpO2UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.MuteAlarm = uibutton(app.UIFigure, 'state');
            app.MuteAlarm.ValueChangedFcn = createCallbackFcn(app, @MuteAlarmValueChanged, true);
            app.MuteAlarm.Enable = 'off';
            app.MuteAlarm.Visible = 'off';
            app.MuteAlarm.Icon = 'muteAlarm1.png';
            app.MuteAlarm.Text = 'MUTE ALARM';
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit alarm thresholds';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TrendsButton = uibutton(app.UIFigure, 'push');
            app.TrendsButton.ButtonPushedFcn = createCallbackFcn(app, @TrendsButtonPushed, true);
            app.TrendsButton.Icon = 'pngwing.com.png';
            app.TrendsButton.Enable = 'off';
            app.TrendsButton.Text = 'Trends';
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = {'HR'; ''};
            app.Image_heart = uiimage(app.UIFigure);
            app.Image_heart.ImageSource = 'greenHeartNew.png';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.HR_value_label = uilabel(app.UIFigure);
            app.HR_value_label.Text = '0';
            app.SpO2Label = uilabel(app.UIFigure);
            app.SpO2Label.Text = 'SpO2';
            app.PercentageLabel = uilabel(app.UIFigure);
            app.PercentageLabel.Text = '%';
            app.SpO2_value_label = uilabel(app.UIFigure);
            app.SpO2_value_label.Text = '0';
            app.MinEditLimitHR = uieditfield(app.UIFigure, 'numeric');
            app.MinEditLimitHR.ValueDisplayFormat = '%.0f';
            app.MinEditLimitHR.Editable = 'off';
            app.MinEditLimitHR.Value = 50;
            app.MaxEditLimitHR = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditLimitHR.Editable = 'off';
            app.MaxEditLimitHR.Value = 100;
            app.MinEditLimitSpO2 = uieditfield(app.UIFigure, 'numeric');
            app.MinEditLimitSpO2.Editable = 'off';
            app.MinEditLimitSpO2.Value = 90;
            app.MaxEditLimitSpO2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditLimitSpO2.Editable = 'off';
            app.MaxEditLimitSpO2.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonitorMeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end