classdef SaveDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxesHR             matlab.ui.control.UIAxes
        UIAxesSPO2           matlab.ui.control.UIAxes
        AlarmsPanel          matlab.ui.container.Panel
        UITableAlarm         matlab.ui.control.Table
        DateandTimePanel     matlab.ui.container.Panel
        TimeEditFieldLabel   matlab.ui.control.Label
        TimeEditField        matlab.ui.control.EditField
        DateLabel            matlab.ui.control.Label
        DateEditField        matlab.ui.control.EditField
        SaveCancelPanel      matlab.ui.container.Panel
        SaveButton           matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        CPREditField_3Label  matlab.ui.control.Label
        CPREditField         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        MonitorApp   % Main app objekt
        MeasurementTable
        AlarmTable
        AlarmThresholdsTable
        Time %Current time
        Date %Current date
        CurrentCPR %CPR number of current patient
        OKCPR = false
    end
    
    methods (Access = private)
        
        function PresentData(app)            
            %x-værdi sættes
            x = datetime(app.MeasurementTable.Time,'Format','HH:mm');
            
            %Plot HR data
            plot(app.UIAxesHR,x,app.MeasurementTable.HR,'-g')
            title(app.UIAxesHR,'HR',"Color",'w')
            xlabel(app.UIAxesHR,'Time','Color','w')
            ylabel(app.UIAxesHR,'BPM','Color','w')
            
            hold(app.UIAxesHR); %holder aksen da der skal plottes af flere omgange
            
            %Plot SPO2 data
            plot(app.UIAxesSPO2,x,app.MeasurementTable.SpO2,'-','Color',"0.00,0.67,1.00")
            title(app.UIAxesSPO2,'SPO2',"Color",'w')
            xlabel(app.UIAxesSPO2,'Time','Color','w')
            ylabel(app.UIAxesSPO2,'%','Color','w')
            
            hold(app.UIAxesSPO2);
            
            %Set current time on GUI
            app.Time = datestr(now, 'HH:MM');
            app.TimeEditField.Value = app.Time;
            
            %Set current date on GUI
            app.Date = datestr(now, 'dd/mm/yyyy');
            app.DateEditField.Value = app.Date;
        end
        
        function ValidateCPR(app)
            if isempty(app.CPREditField.Value) == 0
            %Tjek om CPR-nummeret består af mere end tal - str2double returnerer NaN hvis string indeholder symboler eller bogstaver
            checkCPR = str2double(app.CPREditField.Value);
            if isnan(checkCPR)
                msgbox('Invalid CPR-number - It should only contain digits, try again!', 'Error','error');
                app.OKCPR = false;
            else
                %tjek om CPR-nummeret har det rigtige antal digits
                lengthOfCPR = length(app.CPREditField.Value);
                if lengthOfCPR < 10 || lengthOfCPR > 10
                    msgbox('Invalid CPR-number - It should consist of precisely 10 digits, try again!', 'Error','error');
                    app.OKCPR = false;
                elseif lengthOfCPR==10
                    %Sæt det indtastede CPR-nummer til currentCPR
                    app.CurrentCPR = app.CPREditField.Value;
                    %msgbox('The chosen CPR is now registered.')
                    app.OKCPR = true;
                end
            end
            else
               msgbox('The patients CPR-number must be registered before saving the measurements. Please register the CPR-number and try again!', 'Error','error'); 
            end
        end
        
        function PresentAlarmData(app)
            %Plot alarmer på grafer %denne kode kan nok godt optimeres
            for alarm = 1:length(app.AlarmTable.MeasureType)
                if app.AlarmTable.MeasureType(alarm) == "HR"
                    plot(app.UIAxesHR,datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'),app.AlarmTable.MeasureValue(alarm),'*r')
                end
                
                if app.AlarmTable.MeasureType(alarm) == "SpO2"
                    plot(app.UIAxesSPO2,datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'),app.AlarmTable.MeasureValue(alarm),'*r') 
                end
            end
            
            %Update alarm table
            for alarm = 1:length(app.AlarmTable.MeasureType)
                 tabledata(alarm,:) = table(app.AlarmTable.MeasureType(alarm),app.AlarmTable.MeasureValue(alarm), app.AlarmTable.ThresholdMin(alarm),app.AlarmTable.ThresholdMax(alarm),datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'));
            end
            app.UITableAlarm.Data = tabledata;
            app.UITableAlarm.RowName ='numbered';
        end
        
        function PresentAlarmThresholds(app)
            %Plot grænseværdier
            
             for threshold = 1:length(app.AlarmThresholdsTable.TimeChanged)
                 if threshold  == length(app.AlarmThresholdsTable.TimeChanged)-(length(app.AlarmThresholdsTable.TimeChanged)-1) %Hvis det er den første plads i tabellen, så skal det første punkt på x'aksen sættes til tiden for den første måling! Dette er et issue, da den første treshold i tabllen nemlig sættes allerede i startup-funnktionen for MonitorApp, hvilket betyder at det der ved detførste tidspunkt her ikke vil være en tilsvarende måling, og derfor tages tidspunktet for den første måling 
                    x1 = datetime(app.MeasurementTable.Time(1),'Format','HH:mm');
                 else                      
                    x1 = datetime(app.AlarmThresholdsTable.TimeChanged(threshold),'Format','HH:mm');
                 end 
                     if threshold <= length(app.AlarmThresholdsTable.TimeChanged)-1 %Den skal kun køre følgende kode, hvis der er et tidspunkt det nuværende (derfor skrives der minus 1 af længden på tabellen)
                        x2 = datetime(app.AlarmThresholdsTable.TimeChanged(threshold+1),'Format','HH:mm');
                    elseif threshold == length(app.AlarmThresholdsTable.TimeChanged)
                        x2 = datetime(app.MeasurementTable.Time(end),'Format',"HH:mm"); %tager den sidste værdi i målingen og sætter denne til sluttidspunkt i målingen for den sidste grænseværdi
                    end
                    
                    %HR
                    y1 = app.AlarmThresholdsTable.minHR(threshold);
                    line(app.UIAxesHR,[x1,x2],[y1,y1],'LineStyle','--','Color','b') 
                    y2 = app.AlarmThresholdsTable.maxHR(threshold);
                    line(app.UIAxesHR,[x1,x2],[y2,y2],'LineStyle','--','Color','b')
                    
                    %SPO2
                    y3 = app.AlarmThresholdsTable.minSpO2(threshold);
                    line(app.UIAxesSPO2,[x1,x2],[y3,y3],'LineStyle','--','Color','b') 
                    y4 = app.AlarmThresholdsTable.maxSpO2(threshold);
                    line(app.UIAxesSPO2,[x1,x2],[y4,y4],'LineStyle','--','Color','b')
             end

        end
        
        function PrepareData(app)
            %denne funktion skal gennemløbe de tre præallokerede tabeller
            %og putte de værdier som ikke er 0'er ind i en ny tabel, som
            %skal bruges til at præsentere data
            
            %Fjerner præallokerede rækker fra MeasurementTable 
            measurementsToDelete = app.MeasurementTable.ID == 0;
            app.MeasurementTable(measurementsToDelete,:) = [];
            size(app.MeasurementTable)
            
            %Fjerner præallokerede rækker fra AlarmTable
            alarmsToDelete = app.AlarmTable.ID == 0;
            app.AlarmTable(alarmsToDelete,:) = [];
            size(app.AlarmTable)
            
            %Fjerner præallokerede fra AlarmTable
            alarmThresholdsToDelete = app.AlarmThresholdsTable.ID == 0;
            app.AlarmThresholdsTable( alarmThresholdsToDelete,:) = [];
            size(app.AlarmThresholdsTable)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, datatable, alarmtable, alarmThresholdsTable)
% Store main app in property for CloseRequestFcn to use
app.MonitorApp = mainapp;
app.MeasurementTable = datatable;
app.AlarmTable = alarmtable;
app.AlarmThresholdsTable = alarmThresholdsTable;

app.PrepareData();
app.PresentData();
app.PresentAlarmThresholds();
if sum(app.AlarmTable.ID) > 0
    app.PresentAlarmData();
end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
% Delete the dialog box
delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
% Delete the dialog box
delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
%             if isempty(app.CPREditField.Value) == 0
%             %Tjek om CPR-nummeret består af mere end tal - str2double returnerer NaN hvis string indeholder symboler eller bogstaver
%             checkCPR = str2double(app.CPREditField.Value);
%             if isnan(checkCPR)
%                 msgbox('Invalid CPR-number - It should only contain digits, try again!', 'Error','error');
%                 app.OKCPR = false;
%             else
%                 %tjek om CPR-nummeret har det rigtige antal digits
%                 lengthOfCPR = length(app.CPREditField.Value);
%                 if lengthOfCPR < 10 || lengthOfCPR > 10
%                     msgbox('Invalid CPR-number - It should consist of precisely 10 digits, try again!', 'Error','error');
%                     app.OKCPR = false;
%                 elseif lengthOfCPR==10
%                     %Sæt det indtastede CPR-nummer til currentCPR
%                     app.CurrentCPR = app.CPREditField.Value;
%                     msgbox('The chosen CPR is now registered.')
%                     app.OKCPR = true;
%                 end
%             end
%             else
%                msgbox('The patients CPR-number must be registered before saving the measurements. Please register the CPR-number and try again!', 'Error','error'); 
%             end
%             
            %if isempty(app.CPREditField.Value) == 0 %Isempty returnerer 0 hvis CPR ikke er tom                
                app.ValidateCPR();
                if app.OKCPR
                    %Tag screenshot
                    img = takeScreenDump(); %metode ligger i scriptet
                    
                    %Generatefilename
                    new_currentDate = strrep(app.Date,'/','-');
                    new_currentTime = strrep(app.Time,':','.');
                    formatSpec = 'CPR%s_Date%s_EndTime%s.png';
                    file_name = sprintf(formatSpec,app.CurrentCPR,new_currentDate,new_currentTime);
                    
                    %Save image
                    imwrite(img,file_name) %unikt navn med CPR, dato og sluttid
                    
                    %Messagedialog
                    msgbox('The measurements were succesfully saved!','Success');
                    
                    %Vinduet skal lukkes og returneres til startsiden
                    delete(app)
                else
                     %msgbox('The CPR is not valid. Please write valid CPR','Error','error');
                end
            %else
            %    msgbox('The patients CPR-number must be registered before saving the measurements. Please register the CPR-number and try again!', 'Error','error');
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesHR = uiaxes(app.UIFigure);
            title(app.UIAxesHR, 'Puls')
            xlabel(app.UIAxesHR, 'Tid')
            ylabel(app.UIAxesHR, 'BPM')
            app.UIAxesSPO2 = uiaxes(app.UIFigure);
            title(app.UIAxesSPO2, 'Iltmætning')
            xlabel(app.UIAxesSPO2, 'Tid')
            ylabel(app.UIAxesSPO2, '%')
            app.AlarmsPanel = uipanel(app.UIFigure);
            app.AlarmsPanel.Title = 'Alarms:';
            app.UITableAlarm = uitable(app.AlarmsPanel);
            app.UITableAlarm.ColumnName = {'Type'; 'Value'; 'TMin'; 'TMax'; 'Time'};
            app.UITableAlarm.RowName = {''; ''};
            app.DateandTimePanel = uipanel(app.UIFigure);
            app.DateandTimePanel.Title = 'Date and Time:';
            app.TimeEditFieldLabel = uilabel(app.DateandTimePanel);
            app.TimeEditFieldLabel.Text = 'Time:';
            app.TimeEditField = uieditfield(app.DateandTimePanel, 'text');
            app.TimeEditField.Enable = 'off';
            app.DateLabel = uilabel(app.DateandTimePanel);
            app.DateLabel.Text = 'Date:';
            app.DateEditField = uieditfield(app.DateandTimePanel, 'text');
            app.DateEditField.Enable = 'off';
            app.SaveCancelPanel = uipanel(app.UIFigure);
            app.SaveCancelPanel.Title = 'Save/Cancel';
            app.SaveButton = uibutton(app.SaveCancelPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save-file.png';
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.SaveCancelPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CPREditField_3Label = uilabel(app.SaveCancelPanel);
            app.CPREditField_3Label.Text = 'CPR:';
            app.CPREditField = uieditfield(app.SaveCancelPanel, 'text');
            app.CPREditField.Tooltip = {'XXXXXXXXXX'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end