classdef TrendsAndSaveApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxesHR             matlab.ui.control.UIAxes
        UIAxesSPO2           matlab.ui.control.UIAxes
        AlarmsPanel          matlab.ui.container.Panel
        UITableAlarm         matlab.ui.control.Table
        DateandTimePanel     matlab.ui.container.Panel
        TimeLabel            matlab.ui.control.Label
        DateLabel_2          matlab.ui.control.Label
        SaveCancelPanel      matlab.ui.container.Panel
        SaveButton           matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        CPREditField_3Label  matlab.ui.control.Label
        CPREditField         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        MonitorApp   % Main app objekt
        MeasurementTable
        AlarmTable
        AlarmThresholdsTable
        Time %Current time
        Date %Current date
        CurrentCPR %CPR number of current patient
        OKCPR = false % used in validateCPR
    end
    
    methods (Access = private)
        
        function PresentData(app)
            x = datetime(app.MeasurementTable.Time,'Format','HH:mm');
            
            %Plot HR data
            plot(app.UIAxesHR,x,app.MeasurementTable.HR,'-g')
            title(app.UIAxesHR,'HR',"Color",'w')
            %xlabel(app.UIAxesHR,'Time','Color','w')
            ylabel(app.UIAxesHR,'BPM','Color','w')
            
            hold(app.UIAxesHR);
            
            %Plot SPO2 data
            plot(app.UIAxesSPO2,x,app.MeasurementTable.SpO2,'-','Color',"0.00,0.67,1.00")
            title(app.UIAxesSPO2,'SPO2',"Color",'w')
            %xlabel(app.UIAxesSPO2,'Time','Color','w')
            ylabel(app.UIAxesSPO2,'%','Color','w')
            
            hold(app.UIAxesSPO2);
            
            %Set current time on GUI
            app.Time = datestr(now, 'HH:MM');
            app.TimeLabel.Text = app.Time;
            
            %Set current date on GUI
            app.Date = datestr(now, 'dd/mm/yyyy');
            app.DateLabel_2.Text = app.Date;
        end
        
        function ValidateCPR(app)
            
            %Check if the CPR consists of other values than digits - str2double returns NaN if string contains symbols or letters
            checkCPR = str2double(app.CPREditField.Value);
            
            if isnan(checkCPR)
                msgbox('Invalid CPR-number - It should only contain digits, try again!', 'Error','error');
                app.OKCPR = false;
                app.CPREditField.Value = "";
            else
                %Check if the CPR has the correct amount of digits
                lengthOfCPR = length(app.CPREditField.Value);
                if lengthOfCPR < 10 || lengthOfCPR > 10
                    msgbox('Invalid CPR-number - It should consist of precisely 10 digits, try again!', 'Error','error');
                    app.OKCPR = false;
                    app.CPREditField.Value = "";
                elseif lengthOfCPR==10
                    app.CurrentCPR = app.CPREditField.Value;
                    app.OKCPR = true;
                end
            end
            
        end
        
        function PresentAlarmData(app)
            %Plot HR alarms
            scatter(app.UIAxesHR,app.AlarmTable.Time(app.AlarmTable.MeasureType=="HR"), ... 
                app.AlarmTable.MeasureValue(app.AlarmTable.MeasureType=="HR"), 25,'*','r' );
            
            %Plot SpO2 alarms
            scatter(app.UIAxesSPO2,app.AlarmTable.Time(app.AlarmTable.MeasureType=="SpO2"),...
                app.AlarmTable.MeasureValue(app.AlarmTable.MeasureType=="SpO2"), 25,'*','r' );
            
            %Plot all alarms in table
            app.UITableAlarm.Data = table(app.AlarmTable.MeasureType,app.AlarmTable.MeasureValue, ...
                app.AlarmTable.ThresholdMin,app.AlarmTable.ThresholdMax,datetime(app.AlarmTable.Time,'Format','HH:mm'));
            app.UITableAlarm.RowName ='numbered';
            
%             for alarm = 1:length(app.AlarmTable.MeasureType)
%                 switch app.AlarmTable.MeasureType(alarm)
%                     case "HR"
%                         plot(app.UIAxesHR,datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'),app.AlarmTable.MeasureValue(alarm),'*r')
%                     case "SpO2"
%                         plot(app.UIAxesSPO2,datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'),app.AlarmTable.MeasureValue(alarm),'*r')
%                 end
%                 
%                 tabledata(alarm,:) = table(app.AlarmTable.MeasureType(alarm),app.AlarmTable.MeasureValue(alarm), app.AlarmTable.ThresholdMin(alarm),app.AlarmTable.ThresholdMax(alarm),datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'));
%             end
%             
%             app.UITableAlarm.Data = tabledata;
%             app.UITableAlarm.RowName ='numbered';
        end
        
        function PresentAlarmThresholds(app)
            %Plot alarmtresholds            
            app.AlarmThresholdsTable.TimeChanged(1) = app.MeasurementTable.Time(1);
            app.AlarmThresholdsTable.TimeChanged(end) = app.MeasurementTable.Time(end);
            %app.AlarmThresholdsTable.min(end) = app.MeasurementTable.Time(end);
            stairs(app.UIAxesHR, app.AlarmThresholdsTable.TimeChanged ,app.AlarmThresholdsTable.minHR, '--b');
            stairs(app.UIAxesHR, app.AlarmThresholdsTable.TimeChanged ,app.AlarmThresholdsTable.maxHR, '--b');
            stairs(app.UIAxesSPO2, app.AlarmThresholdsTable.TimeChanged ,app.AlarmThresholdsTable.minSpO2, '--b');
            stairs(app.UIAxesSPO2, app.AlarmThresholdsTable.TimeChanged ,app.AlarmThresholdsTable.maxSpO2, '--b');
            
%             for threshold = 1:length(app.AlarmThresholdsTable.TimeChanged)
%                 %Set X1
%                 switch threshold
%                     case 1 %length(app.AlarmThresholdsTable.TimeChanged)-(length(app.AlarmThresholdsTable.TimeChanged)-1)
%                         %The first timestamp needs to be set from the first measurement timestamp since the alarmthreshold fir sttimestamp is set in the startupfunction, before the measurement has begun.
%                         x1 = datetime(app.MeasurementTable.Time(1),'Format','HH:mm');
%                     otherwise
%                         x1 = datetime(app.AlarmThresholdsTable.TimeChanged(threshold),'Format','HH:mm');
%                 end
%                 
%                 %Set X2
%                 if threshold <= length(app.AlarmThresholdsTable.TimeChanged)-1
%                     x2 = datetime(app.AlarmThresholdsTable.TimeChanged(threshold+1),'Format','HH:mm');
%                 elseif threshold == length(app.AlarmThresholdsTable.TimeChanged)
%                     x2 = datetime(app.MeasurementTable.Time(end),'Format',"HH:mm");
%                 end
%                 
%                 %HR
%                 y1 = app.AlarmThresholdsTable.minHR(threshold);
%                 line(app.UIAxesHR,[x1,x2],[y1,y1],'LineStyle','--','Color','b')
%                 y2 = app.AlarmThresholdsTable.maxHR(threshold);
%                 line(app.UIAxesHR,[x1,x2],[y2,y2],'LineStyle','--','Color','b')
%                 
%                 %SPO2
%                 y3 = app.AlarmThresholdsTable.minSpO2(threshold);
%                 line(app.UIAxesSPO2,[x1,x2],[y3,y3],'LineStyle','--','Color','b')
%                 y4 = app.AlarmThresholdsTable.maxSpO2(threshold);
%                 line(app.UIAxesSPO2,[x1,x2],[y4,y4],'LineStyle','--','Color','b')
%             end
            
        end
        
        function PrepareData(app)
            %Removes pre-allocated rows from tables by vectorize
            app.MeasurementTable = app.MeasurementTable(app.MeasurementTable.ID>0,:);
            app.AlarmTable = app.AlarmTable(app.AlarmTable.ID>0,:);
            app.AlarmThresholdsTable = app.AlarmThresholdsTable(app.AlarmThresholdsTable.ID>0,:);
           
            %measurementsToDelete = app.MeasurementTable.ID == 0;
            %app.MeasurementTable(measurementsToDelete,:) = [];
            %size(app.MeasurementTable);
            
            %Removes pre-allocated rows from AlarmTable by vectorize          
            %alarmsToDelete = app.AlarmTable.ID == 0;
            %app.AlarmTable(alarmsToDelete,:) = [];
            %size(app.AlarmTable);
            
            %Removes pre-allocated rows from AlarmTable by vectorize
            %alarmThresholdsToDelete = app.AlarmThresholdsTable.ID == 0;
            %app.AlarmThresholdsTable( alarmThresholdsToDelete,:) = [];
            %size(app.AlarmThresholdsTable);
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, datatable, alarmtable, alarmThresholdsTable)
            app.UIFigure.Name = "Trends and save";
            
            % Store main app in property for CloseRequestFcn to use
            app.MonitorApp = mainapp;
            app.MeasurementTable = datatable;
            app.AlarmTable = alarmtable;
            app.AlarmThresholdsTable = alarmThresholdsTable;
            
            app.PrepareData();
            app.PresentData();
            app.PresentAlarmThresholds();
            
            if sum(app.AlarmTable.ID) > 0
                app.PresentAlarmData();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MonitorApp.TrendsButton.Enable = true;
            
            msgbox('Cancel was pressed. The measurements were not saved!','Cancel');
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MonitorApp.TrendsButton.Enable = true;
            
            msgbox('The trends window was closed. The measurements were not saved!','Exit');
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % First the CPR is validated, and the OKCPR value is set to true or false
            app.ValidateCPR();
            
            if app.OKCPR
                %Take screendump
                img = takeScreenDump();
                
                %Generate filename
                new_currentDate = strrep(app.Date,'/','-');
                new_currentTime = strrep(app.Time,':','.');
                formatSpec = 'CPR%s_Date%s_EndTime%s.png';
                file_name = sprintf(formatSpec,app.CurrentCPR,new_currentDate,new_currentTime);
                
                %Save image
                imwrite(img,file_name) %unikt navn med CPR, dato og sluttid
                
                %Messagedialog
                msgbox('The measurements were succesfully saved!','Success');
                
                %Returns to MonitorAppDialog
                delete(app)
            end
            
        end

        % Value changing function: CPREditField
        function CPREditFieldValueChanging(app, event)
            
            app.SaveButton.Enable = true;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesHR = uiaxes(app.UIFigure);
            title(app.UIAxesHR, 'Puls')
            xlabel(app.UIAxesHR, '')
            ylabel(app.UIAxesHR, 'BPM')
            app.UIAxesSPO2 = uiaxes(app.UIFigure);
            title(app.UIAxesSPO2, 'Iltmætning')
            xlabel(app.UIAxesSPO2, '')
            ylabel(app.UIAxesSPO2, '%')
            app.AlarmsPanel = uipanel(app.UIFigure);
            app.AlarmsPanel.Title = 'Alarms:';
            app.UITableAlarm = uitable(app.AlarmsPanel);
            app.UITableAlarm.ColumnName = {'Type'; 'Value'; 'TMin'; 'TMax'; 'Time'};
            app.UITableAlarm.RowName = {''; ''};
            app.DateandTimePanel = uipanel(app.UIFigure);
            app.DateandTimePanel.Title = 'Date and Time:';
            app.TimeLabel = uilabel(app.DateandTimePanel);
            app.TimeLabel.Text = 'Time';
            app.DateLabel_2 = uilabel(app.DateandTimePanel);
            app.DateLabel_2.Text = 'Date';
            app.SaveCancelPanel = uipanel(app.UIFigure);
            app.SaveCancelPanel.Title = 'Save/Cancel';
            app.SaveButton = uibutton(app.SaveCancelPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save-file.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.SaveCancelPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CPREditField_3Label = uilabel(app.SaveCancelPanel);
            app.CPREditField_3Label.Text = 'CPR:';
            app.CPREditField = uieditfield(app.SaveCancelPanel, 'text');
            app.CPREditField.ValueChangingFcn = createCallbackFcn(app, @CPREditFieldValueChanging, true);
            app.CPREditField.Tooltip = {'XXXXXXXXXX'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrendsAndSaveApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end